classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Label_2                   matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        GUIparaprocesamientodesealesECGLabel  matlab.ui.control.Label
        FuncinDropDown            matlab.ui.control.DropDown
        FuncinDropDownLabel       matlab.ui.control.Label
        GuardarimagenButton_2     matlab.ui.control.Button
        GuardarimagenButton       matlab.ui.control.Button
        SeleccionararchivoButton  matlab.ui.control.Button
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SeleccionararchivoButton
        function SeleccionararchivoButtonPushed(app, event)
            global Ecg1;
            global g;

            [file,path,indx] = uigetfile('*');
           % fig = uifigure;
           tlabel = app.Label;
            %tlabel.Text = 'Options';
          if isequal(file,0)
               tlabel.Text='Selección del archivo cancelada';
            else
               tlabel.Text=(['El usuario seleccióno ', fullfile(path, file),... 
                     ' : ', num2str(indx)]);
                     %app.Label.String = '1';   
                     [~, ~, fExt] = fileparts(file);
                   switch lower(fExt)
                       case '.mat'
                             carga = fullfile(path,file);
                               g=load(carga);
                               Ecg1=g.val;
                             
                                %mostrar ecg original
                                plot(app.UIAxes2,Ecg1);
                                xlabel (app.UIAxes2,'Muestras')
                                ylabel (app.UIAxes2,'Amplitud')
                                title (app.UIAxes2,'Señal ECG Original')
                  
                   case '.csv'
                          carga = fullfile(path,file);
                       
                     
                           [num,~,~] = xlsread(carga);
                            %combine data as you want:
                            %AllData={txt,num};%as you want
                           
                            prompt = '¿En que columna se encuantran tus datos? ';
                            col = input(prompt);
                           Ecg1=num(:,col);
                            plot(app.UIAxes2,Ecg1);
                                xlabel (app.UIAxes2,'Muestras')
                                ylabel (app.UIAxes2,'Amplitud')
                                title (app.UIAxes2,'Señal ECG Original')

                                case '.dat'
                           carga = fullfile(path,file);

                        %A = txt2mat(carga,0,'ReadMode','line');
                         %A = txt2mat(carga,0,5,'ReadMode','block')
                         A = txt2mat(carga,0,1);
                        A = A(any(isfinite(A),2),:);
                        Ecg1=A(:,1);
                        plot(app.UIAxes2,Ecg1);
                                xlabel (app.UIAxes2,'Muestras')
                                ylabel (app.UIAxes2,'Amplitud')
                                title (app.UIAxes2,'Señal ECG Original')
                        case '.edf'
                            prompt = '¿En que columna se encuantran tus datos? ';
                            col = input(prompt)
                           carga = fullfile(path,file);
                          [hdr, record] = edfreadUntilDone(carga);
                           Ecg1=record(col,:);
                              plot(app.UIAxes2,Ecg1);
                                xlabel (app.UIAxes2,'Muestras')
                                ylabel (app.UIAxes2,'Amplitud')
                                title (app.UIAxes2,'Señal ECG Original')
                                                
                           
                   end
           end


            %comprobar si tienes la version de mathlab correcta
           % if verLessThan('matlab', '9,11')
            %    app.Label='You are using an old matlab version, the GUI might not be fully functional';
           % end

        end

        % Value changed function: FuncinDropDown
        function FuncinDropDownValueChanged(app, event)
             global Ecg1
             %global fs
             %global ECG2
            % global z
             global g
             %global t
            % global ECG_data
             %global noisyECG_withTrend
            % global smoothECG
             %global locs_Rwave
             %global locs_Swave
             %global locs_Qwave

            value = app.FuncinDropDown.Value;
            switch value
                
                case 'Normalizacion'
                    %%
                    cla(app.UIAxes);
                    legend(app.UIAxes,'off')
                    

                    %normalizacion
                   
                    fs=1000; %frecuencia de muestreo (INFO. OBTENIDA DE ARCHIVO DE DESCARGA)
                    gain=26599; %equivale a 1mV y es la relación entre la
                    %amplitud de una señal de salida respecto a la señal de entrada
                    c=Ecg1/gain; %con esto ajustamos la amplitud

                    %NORMALIZAMOS LA SEÑAL DE 0-1
                    no1=normalize(c,'range');
                    %global ECG2
                    ECG2=no1(1:1:length(no1)); 
                    t=(0:length(ECG2)-1)/fs; %10 seconds data 
             
                    plot(app.UIAxes,t,ECG2) %PLOTEAMOS LA SEÑAL NORMALIZADA DE 0-1 RESPECTO AL TIEMPO (10s)
                    ylabel(app.UIAxes,'mV');
                    xlabel(app.UIAxes,'Tiempo (s)');
                    title (app.UIAxes,'Normalización');
                    %%
                case 'FFT'
                    cla(app.UIAxes);

                   
                    %cosas para hacerlo invidulmente
                    gain=26599;
                    c=Ecg1/gain;
                    no1=normalize(c,'range');
                    ECG2=no1(1:1:length(no1));
                    fs=1000;
                    legend(app.UIAxes,'off')


                    %fft
                    
                    N=length(ECG2);
                    %dt=1./fs;
                    %resolucion 
                    df=fs/N;
                    f=(-N/2:N/2-1)*df;
                    z=fft(ECG2)/N;
                    plot(app.UIAxes,f,fftshift(abs(z)))
                    ylabel(app.UIAxes,'mV');
                    xlabel(app.UIAxes,'Tiempo (s)');
                    title (app.UIAxes,'Señal ECG FFT');
           
                case 'DEP'
                    cla(app.UIAxes);
                    legend(app.UIAxes,'off')

                   
                     %cosas para hacerlo invidulmente
                    gain=26599;
                    c=Ecg1/gain;
                    no1=normalize(c,'range');
                    ECG2=no1(1:1:length(no1));
                    N=length(ECG2);
                    z=fft(ECG2)/N;


                    %codigo de densidad
                    pxx = pwelch(z);
                    % axis tight;
                    plot(app.UIAxes,pxx)
                    ylabel(app.UIAxes,'mV');
                    xlabel(app.UIAxes,'Tiempo (s)');
                    title (app.UIAxes,'DEP');
                    hold(app.UIAxes,'off')

                     case 'Señal sin tendencia'
                     cla(app.UIAxes);
                     legend(app.UIAxes,'off')


                    %cosas para hacerlo invidulmente
                    %noisyECG_withTrend=g.val(1,:);
                    noisyECG_withTrend=Ecg1;


                    %codigo de la señal sin tendencia

                    nnoisyECG_withTrend = noisyECG_withTrend'; %noisy con 2 nn
                    t = 1:length(noisyECG_withTrend);


                    [p,s,mu] = polyfit((1:numel(noisyECG_withTrend))',nnoisyECG_withTrend,6)

                    f_y = polyval(p,(1:numel(noisyECG_withTrend))',[],mu);
                    
                    ECG_data = nnoisyECG_withTrend - f_y;    % Detrend data
                    
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,ECG_data)
                    
                    %ax = axis;
                    %axis([ax(1:2) -1.2 1.2])
                    title(app.UIAxes,'Señal de ECG sin tendencia')
                    xlabel(app.UIAxes,'Muestras')
                    ylabel(app.UIAxes,'mV')
                    hold(app.UIAxes,'off')
                  

                case 'Picos y ondas sin filtro'
                    cla(app.UIAxes);
                    %


                    %codigo para hacerlo individual 
                    %noisyECG_withTrend=g.val(1,:);
                    noisyECG_withTrend=Ecg1;

                     nnoisyECG_withTrend = noisyECG_withTrend';
                     [p,s,mu] = polyfit((1:numel(noisyECG_withTrend))',nnoisyECG_withTrend,6)
                     f_y = polyval(p,(1:numel(noisyECG_withTrend))',[],mu);
                     ECG_data = nnoisyECG_withTrend - f_y; 
                     t = 1:length(noisyECG_withTrend);


                    %codigo pa los picos
                    
                    
                    [~,locs_Rwave] = findpeaks(ECG_data,'MinPeakHeight',0.5,...
                                    'MinPeakDistance',200);
                    ECG_inverted = -ECG_data;

                    [~,locs_Swave] = findpeaks(ECG_inverted,'MinPeakHeight',0.5,...
                                                            'MinPeakDistance',200);
                   hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,ECG_data)
                    

                    plot(app.UIAxes,locs_Rwave,ECG_data(locs_Rwave),'o','MarkerFaceColor','red')
                    
                    plot(app.UIAxes,locs_Swave,ECG_data(locs_Swave),'x','MarkerFaceColor','blue')
                    %axis([0 1850 -1.1 1.1])
                    
                    legend(app.UIAxes,'Señal ECG','Ondas R','Ondas S')
                    xlabel(app.UIAxes,'Muestra')
                    ylabel(app.UIAxes,'mV')
                    title(app.UIAxes,'Picos y ondas sin filtro')
                    hold(app.UIAxes,'off')


                case 'Señal filtrada'
                    cla(app.UIAxes);
                    legend(app.UIAxes,'off')


                    %codigo para hacerlo individual
                    %noisyECG_withTrend=g.val(1,:);
                    noisyECG_withTrend=Ecg1;
                     nnoisyECG_withTrend = noisyECG_withTrend';
                     [p,s,mu] = polyfit((1:numel(noisyECG_withTrend))',nnoisyECG_withTrend,6)
                     f_y = polyval(p,(1:numel(noisyECG_withTrend))',[],mu);
                     ECG_data = nnoisyECG_withTrend - f_y;
                     t = 1:length(noisyECG_withTrend);


                    %codigo para filtro


                    smoothECG = sgolayfilt(ECG_data,7,21);

                    
                    plot(app.UIAxes,t,ECG_data,'blue',t,smoothECG,'red')
                    
                    %axis tight
                    xlabel(app.UIAxes,'Muestras')
                    ylabel(app.UIAxes,'mV')
                    legend(app.UIAxes,'Señal de ECG con ruido','Señal Filtrada')
                    title(app.UIAxes,'Filtrado de la señal de ECG con ruido')
                    hold(app.UIAxes,'off')


                case 'Picos de umbral en la señal'
                    cla(app.UIAxes);


                    ecg = Ecg1(:); % vectorize
                    delay = 0;
                    skip = 0;                                                                  % becomes one when a T wave is detected
                    m_selected_RR = 0;
                    mean_RR = 0;
                    ser_back = 0; 
                    ax = zeros(1,6);
                    fs=1000;
                    gr = 1;  
                    
                    
                     ecg = ecg - mean(ecg);
                    
                    
                    f1=5;                                                                      % cuttoff low frequency to get rid of baseline wander
                     f2=15;                                                                     % cuttoff frequency to discard high frequency noise
                     Wn=[f1 f2]*2/fs;                                                           % cutt off based on fs
                     N = 3;                                                                     % order of 3 less processing
                     [a,b] = butter(N,Wn);   
                    
                    
                    ecg_h = filtfilt(a,b,ecg);
                     ecg_h = ecg_h/ max( abs(ecg_h));
                    
                     if fs ~= 200
                     int_c = (5-1)/(fs*1/40);
                     b = interp1(1:5,[1 2 0 -2 -1].*(1/8)*fs,1:int_c:5);
                    else
                     b = [1 2 0 -2 -1].*(1/8)*fs;   
                    end
                    
                    ecg_d = filtfilt(b,1,ecg_h);
                     ecg_d = ecg_d/max(ecg_d);
                     ecg_s = ecg_d.^2;
                     if fs ~= 200
                     int_c = (5-1)/(fs*1/40);
                     b = interp1(1:5,[1 2 0 -2 -1].*(1/8)*fs,1:int_c:5);
                    else
                     b = [1 2 0 -2 -1].*(1/8)*fs;   
                    end
                     ecg_d = filtfilt(b,1,ecg_h);
                     ecg_d = ecg_d/max(ecg_d);


                     ecg_m = conv(ecg_s ,ones(1 ,round(0.150*fs))/round(0.150*fs));
                     
                    
                      [pks,locs] = findpeaks(ecg_m,'MINPEAKDISTANCE',round(0.2*fs));
                    %% =================== Initialize Some Other Parameters =============== %%
                    LLp = length(pks);
                    % ---------------- Stores QRS wrt Sig and Filtered Sig ------------------%
                    qrs_c = zeros(1,LLp);           % amplitude of R
                    qrs_i = zeros(1,LLp);           % index
                    qrs_i_raw = zeros(1,LLp);       % amplitude of R
                    qrs_amp_raw= zeros(1,LLp);      % Index
                    % ------------------- Noise Buffers ---------------------------------%
                    nois_c = zeros(1,LLp);
                    nois_i = zeros(1,LLp);
                    % ------------------- Buffers for Signal and Noise ----------------- %
                    SIGL_buf = zeros(1,LLp);
                    NOISL_buf = zeros(1,LLp);
                    SIGL_buf1 = zeros(1,LLp);
                    NOISL_buf1 = zeros(1,LLp);
                    THRS_buf1 = zeros(1,LLp);
                    THRS_buf = zeros(1,LLp);
                    %% initialize the training phase (2 seconds of the signal) to determine the THR_SIG and THR_NOISE
                    THR_SIG = max(ecg_m(1:2*fs))*1/3;                                          % 0.25 of the max amplitude 
                    THR_NOISE = mean(ecg_m(1:2*fs))*1/2;                                       % 0.5 of the mean signal is considered to be noise
                    SIG_LEV= THR_SIG;
                    NOISE_LEV = THR_NOISE;
                    %% Initialize bandpath filter threshold(2 seconds of the bandpass signal)
                    THR_SIG1 = max(ecg_h(1:2*fs))*1/3;                                          % 0.25 of the max amplitude 
                    THR_NOISE1 = mean(ecg_h(1:2*fs))*1/2; 
                    SIG_LEV1 = THR_SIG1;                                                        % Signal level in Bandpassed filter
                    NOISE_LEV1 = THR_NOISE1;                                                    % Noise level in Bandpassed filter
                    %% ============ Thresholding and desicion rule ============= %%
                    Beat_C = 0;                                                                 % Raw Beats
                    Beat_C1 = 0;                                                                % Filtered Beats
                    Noise_Count = 0;                                                            % Noise Counter
                    for i = 1 : LLp  
                       %% ===== locate the corresponding peak in the filtered signal === %%
                        if locs(i)-round(0.150*fs)>= 1 && locs(i)<= length(ecg_h)
                              [y_i,x_i] = max(ecg_h(locs(i)-round(0.150*fs):locs(i)));
                           else
                              if i == 1
                                [y_i,x_i] = max(ecg_h(1:locs(i)));
                                ser_back = 1;
                              elseif locs(i)>= length(ecg_h)
                                [y_i,x_i] = max(ecg_h(locs(i)-round(0.150*fs):end));
                              end       
                        end       
                      %% ================= update the heart_rate ==================== %% 
                        if Beat_C >= 9        
                            diffRR = diff(qrs_i(Beat_C-8:Beat_C));                                   % calculate RR interval
                            mean_RR = mean(diffRR);                                            % calculate the mean of 8 previous R waves interval
                            comp =qrs_i(Beat_C)-qrs_i(Beat_C-1);                                     % latest RR
                        
                            if comp <= 0.92*mean_RR || comp >= 1.16*mean_RR
                         % ------ lower down thresholds to detect better in MVI -------- %
                                    THR_SIG = 0.5*(THR_SIG);
                                    THR_SIG1 = 0.5*(THR_SIG1);               
                            else
                                m_selected_RR = mean_RR;                                       % The latest regular beats mean
                            end 
                              
                        end
                        
                     %% == calculate the mean last 8 R waves to ensure that QRS is not ==== %%
                           if m_selected_RR
                               test_m = m_selected_RR;                                         %if the regular RR availabe use it   
                           elseif mean_RR && m_selected_RR == 0
                               test_m = mean_RR;   
                           else
                               test_m = 0;
                           end
                            
                        if test_m
                              if (locs(i) - qrs_i(Beat_C)) >= round(1.66*test_m)                  % it shows a QRS is missed 
                                  [pks_temp,locs_temp] = max(ecg_m(qrs_i(Beat_C)+ round(0.200*fs):locs(i)-round(0.200*fs))); % search back and locate the max in this interval
                                  locs_temp = qrs_i(Beat_C)+ round(0.200*fs) + locs_temp -1;      % location 
                                 
                                  if pks_temp > THR_NOISE
                                   Beat_C = Beat_C + 1;
                                   qrs_c(Beat_C) = pks_temp;
                                   qrs_i(Beat_C) = locs_temp;      
                                  % ------------- Locate in Filtered Sig ------------- %
                                   if locs_temp <= length(ecg_h)
                                      [y_i_t,x_i_t] = max(ecg_h(locs_temp-round(0.150*fs):locs_temp));
                                   else
                                      [y_i_t,x_i_t] = max(ecg_h(locs_temp-round(0.150*fs):end));
                                   end
                                  % ----------- Band pass Sig Threshold ------------------%
                                   if y_i_t > THR_NOISE1 
                                      Beat_C1 = Beat_C1 + 1;
                                      qrs_i_raw(Beat_C1) = locs_temp-round(0.150*fs)+ (x_i_t - 1);% save index of bandpass 
                                      qrs_amp_raw(Beat_C1) = y_i_t;                               % save amplitude of bandpass 
                                      SIG_LEV1 = 0.25*y_i_t + 0.75*SIG_LEV1;                      % when found with the second thres 
                                   end
                                   
                                   not_nois = 1;
                                   SIG_LEV = 0.25*pks_temp + 0.75*SIG_LEV ;                       % when found with the second threshold             
                                 end             
                              else
                                  not_nois = 0;         
                              end
                        end
                      
                        %% ===================  find noise and QRS peaks ================== %%
                        if pks(i) >= THR_SIG      
                          % ------ if No QRS in 360ms of the previous QRS See if T wave ------%
                           if Beat_C >= 3
                              if (locs(i)-qrs_i(Beat_C)) <= round(0.3600*fs)
                                  Slope1 = mean(diff(ecg_m(locs(i)-round(0.075*fs):locs(i))));       % mean slope of the waveform at that position
                                  Slope2 = mean(diff(ecg_m(qrs_i(Beat_C)-round(0.075*fs):qrs_i(Beat_C)))); % mean slope of previous R wave
                                  if abs(Slope1) <= abs(0.5*(Slope2))                              % slope less then 0.5 of previous R
                                     Noise_Count = Noise_Count + 1;
                                     nois_c(Noise_Count) = pks(i);
                                     nois_i(Noise_Count) = locs(i);
                                     skip = 1;                                                 % T wave identification
                                     % ----- adjust noise levels ------ %
                                     NOISE_LEV1 = 0.125*y_i + 0.875*NOISE_LEV1;
                                     NOISE_LEV = 0.125*pks(i) + 0.875*NOISE_LEV; 
                                  else
                                     skip = 0;
                                  end
                                
                               end
                            end
                            %---------- skip is 1 when a T wave is detected -------------- %
                            if skip == 0    
                              Beat_C = Beat_C + 1;
                              qrs_c(Beat_C) = pks(i);
                              qrs_i(Beat_C) = locs(i);
                            
                            %--------------- bandpass filter check threshold --------------- %
                              if y_i >= THR_SIG1  
                                  Beat_C1 = Beat_C1 + 1;
                                  if ser_back 
                                     qrs_i_raw(Beat_C1) = x_i;                                 % save index of bandpass 
                                  else
                                     qrs_i_raw(Beat_C1)= locs(i)-round(0.150*fs)+ (x_i - 1);   % save index of bandpass 
                                  end
                                  qrs_amp_raw(Beat_C1) =  y_i;                                 % save amplitude of bandpass 
                                  SIG_LEV1 = 0.125*y_i + 0.875*SIG_LEV1;                       % adjust threshold for bandpass filtered sig
                              end
                             SIG_LEV = 0.125*pks(i) + 0.875*SIG_LEV ;                          % adjust Signal level
                            end
                                  
                        elseif (THR_NOISE <= pks(i)) && (pks(i) < THR_SIG)
                             NOISE_LEV1 = 0.125*y_i + 0.875*NOISE_LEV1;                        % adjust Noise level in filtered sig
                             NOISE_LEV = 0.125*pks(i) + 0.875*NOISE_LEV;                       % adjust Noise level in MVI       
                        elseif pks(i) < THR_NOISE
                            Noise_Count = Noise_Count + 1;
                            nois_c(Noise_Count) = pks(i);
                            nois_i(Noise_Count) = locs(i);    
                            NOISE_LEV1 = 0.125*y_i + 0.875*NOISE_LEV1;                         % noise level in filtered signal    
                            NOISE_LEV = 0.125*pks(i) + 0.875*NOISE_LEV;                        % adjust Noise level in MVI     
                        end
                                   
                        %% ================== adjust the threshold with SNR ============= %%
                        if NOISE_LEV ~= 0 || SIG_LEV ~= 0
                            THR_SIG = NOISE_LEV + 0.25*(abs(SIG_LEV - NOISE_LEV));
                            THR_NOISE = 0.5*(THR_SIG);
                        end
                        
                        %------ adjust the threshold with SNR for bandpassed signal -------- %
                        if NOISE_LEV1 ~= 0 || SIG_LEV1 ~= 0
                            THR_SIG1 = NOISE_LEV1 + 0.25*(abs(SIG_LEV1 - NOISE_LEV1));
                            THR_NOISE1 = 0.5*(THR_SIG1);
                        end
                        
                        
                    %--------- take a track of thresholds of smoothed signal -------------%
                    SIGL_buf(i) = SIG_LEV;
                    NOISL_buf(i) = NOISE_LEV;
                    THRS_buf(i) = THR_SIG;
                    %-------- take a track of thresholds of filtered signal ----------- %
                    SIGL_buf1(i) = SIG_LEV1;
                    NOISL_buf1(i) = NOISE_LEV1;
                    THRS_buf1(i) = THR_SIG1;
                    % ----------------------- reset parameters -------------------------- % 
                    skip = 0;                                                   
                    not_nois = 0; 
                    ser_back = 0;    
                    end
                    %% ======================= Adjust Lengths ============================ %%
                    qrs_i_raw = qrs_i_raw(1:Beat_C1);
                    qrs_amp_raw = qrs_amp_raw(1:Beat_C1);
                    qrs_c = qrs_c(1:Beat_C);
                    qrs_i = qrs_i(1:Beat_C);

                    hold(app.UIAxes,'on')
                    legend(app.UIAxes,'off')
                    
                     plot(app.UIAxes,ecg_h);
                       title(app.UIAxes,'Picos de la señal');
                       %axis tight;
                       hold on,plot(app.UIAxes,qrs_i_raw,qrs_amp_raw,'o');
                       
                                           hold(app.UIAxes,'off')


                case 'QRS on MVI signal'
                    cla(app.UIAxes);


                    ecg = Ecg1(:); % vectorize
                    delay = 0;
                    skip = 0;                                                                  % becomes one when a T wave is detected
                    m_selected_RR = 0;
                    mean_RR = 0;
                    ser_back = 0; 
                    ax = zeros(1,6);
                    fs=1000;
                    gr = 1;  
                    
                    
                     ecg = ecg - mean(ecg);
                    
                    
                    f1=5;                                                                      % cuttoff low frequency to get rid of baseline wander
                     f2=15;                                                                     % cuttoff frequency to discard high frequency noise
                     Wn=[f1 f2]*2/fs;                                                           % cutt off based on fs
                     N = 3;                                                                     % order of 3 less processing
                     [a,b] = butter(N,Wn);   
                    
                    
                    ecg_h = filtfilt(a,b,ecg);
                     ecg_h = ecg_h/ max( abs(ecg_h));
                    
                     if fs ~= 200
                     int_c = (5-1)/(fs*1/40);
                     b = interp1(1:5,[1 2 0 -2 -1].*(1/8)*fs,1:int_c:5);
                    else
                     b = [1 2 0 -2 -1].*(1/8)*fs;   
                    end
                    
                    ecg_d = filtfilt(b,1,ecg_h);
                     ecg_d = ecg_d/max(ecg_d);
                     ecg_s = ecg_d.^2;
                     if fs ~= 200
                     int_c = (5-1)/(fs*1/40);
                     b = interp1(1:5,[1 2 0 -2 -1].*(1/8)*fs,1:int_c:5);
                    else
                     b = [1 2 0 -2 -1].*(1/8)*fs;   
                    end


                     ecg_m = conv(ecg_s ,ones(1 ,round(0.150*fs))/round(0.150*fs));
                    
                    
                      [pks,locs] = findpeaks(ecg_m,'MINPEAKDISTANCE',round(0.2*fs));
                    %% =================== Initialize Some Other Parameters =============== %%
                    LLp = length(pks);
                    % ---------------- Stores QRS wrt Sig and Filtered Sig ------------------%
                    qrs_c = zeros(1,LLp);           % amplitude of R
                    qrs_i = zeros(1,LLp);           % index
                    qrs_i_raw = zeros(1,LLp);       % amplitude of R
                    qrs_amp_raw= zeros(1,LLp);      % Index
                    % ------------------- Noise Buffers ---------------------------------%
                    nois_c = zeros(1,LLp);
                    nois_i = zeros(1,LLp);
                    % ------------------- Buffers for Signal and Noise ----------------- %
                    SIGL_buf = zeros(1,LLp);
                    NOISL_buf = zeros(1,LLp);
                    SIGL_buf1 = zeros(1,LLp);
                    NOISL_buf1 = zeros(1,LLp);
                    THRS_buf1 = zeros(1,LLp);
                    THRS_buf = zeros(1,LLp);
                    %% initialize the training phase (2 seconds of the signal) to determine the THR_SIG and THR_NOISE
                    THR_SIG = max(ecg_m(1:2*fs))*1/3;                                          % 0.25 of the max amplitude 
                    THR_NOISE = mean(ecg_m(1:2*fs))*1/2;                                       % 0.5 of the mean signal is considered to be noise
                    SIG_LEV= THR_SIG;
                    NOISE_LEV = THR_NOISE;
                    %% Initialize bandpath filter threshold(2 seconds of the bandpass signal)
                    THR_SIG1 = max(ecg_h(1:2*fs))*1/3;                                          % 0.25 of the max amplitude 
                    THR_NOISE1 = mean(ecg_h(1:2*fs))*1/2; 
                    SIG_LEV1 = THR_SIG1;                                                        % Signal level in Bandpassed filter
                    NOISE_LEV1 = THR_NOISE1;                                                    % Noise level in Bandpassed filter
                    %% ============ Thresholding and desicion rule ============= %%
                    Beat_C = 0;                                                                 % Raw Beats
                    Beat_C1 = 0;                                                                % Filtered Beats
                    Noise_Count = 0;                                                            % Noise Counter
                    for i = 1 : LLp  
                       %% ===== locate the corresponding peak in the filtered signal === %%
                        if locs(i)-round(0.150*fs)>= 1 && locs(i)<= length(ecg_h)
                              [y_i,x_i] = max(ecg_h(locs(i)-round(0.150*fs):locs(i)));
                           else
                              if i == 1
                                [y_i,x_i] = max(ecg_h(1:locs(i)));
                                ser_back = 1;
                              elseif locs(i)>= length(ecg_h)
                                [y_i,x_i] = max(ecg_h(locs(i)-round(0.150*fs):end));
                              end       
                        end       
                      %% ================= update the heart_rate ==================== %% 
                        if Beat_C >= 9        
                            diffRR = diff(qrs_i(Beat_C-8:Beat_C));                                   % calculate RR interval
                            mean_RR = mean(diffRR);                                            % calculate the mean of 8 previous R waves interval
                            comp =qrs_i(Beat_C)-qrs_i(Beat_C-1);                                     % latest RR
                        
                            if comp <= 0.92*mean_RR || comp >= 1.16*mean_RR
                         % ------ lower down thresholds to detect better in MVI -------- %
                                    THR_SIG = 0.5*(THR_SIG);
                                    THR_SIG1 = 0.5*(THR_SIG1);               
                            else
                                m_selected_RR = mean_RR;                                       % The latest regular beats mean
                            end 
                              
                        end
                        
                     %% == calculate the mean last 8 R waves to ensure that QRS is not ==== %%
                           if m_selected_RR
                               test_m = m_selected_RR;                                         %if the regular RR availabe use it   
                           elseif mean_RR && m_selected_RR == 0
                               test_m = mean_RR;   
                           else
                               test_m = 0;
                           end
                            
                        if test_m
                              if (locs(i) - qrs_i(Beat_C)) >= round(1.66*test_m)                  % it shows a QRS is missed 
                                  [pks_temp,locs_temp] = max(ecg_m(qrs_i(Beat_C)+ round(0.200*fs):locs(i)-round(0.200*fs))); % search back and locate the max in this interval
                                  locs_temp = qrs_i(Beat_C)+ round(0.200*fs) + locs_temp -1;      % location 
                                 
                                  if pks_temp > THR_NOISE
                                   Beat_C = Beat_C + 1;
                                   qrs_c(Beat_C) = pks_temp;
                                   qrs_i(Beat_C) = locs_temp;      
                                  % ------------- Locate in Filtered Sig ------------- %
                                   if locs_temp <= length(ecg_h)
                                      [y_i_t,x_i_t] = max(ecg_h(locs_temp-round(0.150*fs):locs_temp));
                                   else
                                      [y_i_t,x_i_t] = max(ecg_h(locs_temp-round(0.150*fs):end));
                                   end
                                  % ----------- Band pass Sig Threshold ------------------%
                                   if y_i_t > THR_NOISE1 
                                      Beat_C1 = Beat_C1 + 1;
                                      qrs_i_raw(Beat_C1) = locs_temp-round(0.150*fs)+ (x_i_t - 1);% save index of bandpass 
                                      qrs_amp_raw(Beat_C1) = y_i_t;                               % save amplitude of bandpass 
                                      SIG_LEV1 = 0.25*y_i_t + 0.75*SIG_LEV1;                      % when found with the second thres 
                                   end
                                   
                                   not_nois = 1;
                                   SIG_LEV = 0.25*pks_temp + 0.75*SIG_LEV ;                       % when found with the second threshold             
                                 end             
                              else
                                  not_nois = 0;         
                              end
                        end
                      
                        %% ===================  find noise and QRS peaks ================== %%
                        if pks(i) >= THR_SIG      
                          % ------ if No QRS in 360ms of the previous QRS See if T wave ------%
                           if Beat_C >= 3
                              if (locs(i)-qrs_i(Beat_C)) <= round(0.3600*fs)
                                  Slope1 = mean(diff(ecg_m(locs(i)-round(0.075*fs):locs(i))));       % mean slope of the waveform at that position
                                  Slope2 = mean(diff(ecg_m(qrs_i(Beat_C)-round(0.075*fs):qrs_i(Beat_C)))); % mean slope of previous R wave
                                  if abs(Slope1) <= abs(0.5*(Slope2))                              % slope less then 0.5 of previous R
                                     Noise_Count = Noise_Count + 1;
                                     nois_c(Noise_Count) = pks(i);
                                     nois_i(Noise_Count) = locs(i);
                                     skip = 1;                                                 % T wave identification
                                     % ----- adjust noise levels ------ %
                                     NOISE_LEV1 = 0.125*y_i + 0.875*NOISE_LEV1;
                                     NOISE_LEV = 0.125*pks(i) + 0.875*NOISE_LEV; 
                                  else
                                     skip = 0;
                                  end
                                
                               end
                            end
                            %---------- skip is 1 when a T wave is detected -------------- %
                            if skip == 0    
                              Beat_C = Beat_C + 1;
                              qrs_c(Beat_C) = pks(i);
                              qrs_i(Beat_C) = locs(i);
                            
                            %--------------- bandpass filter check threshold --------------- %
                              if y_i >= THR_SIG1  
                                  Beat_C1 = Beat_C1 + 1;
                                  if ser_back 
                                     qrs_i_raw(Beat_C1) = x_i;                                 % save index of bandpass 
                                  else
                                     qrs_i_raw(Beat_C1)= locs(i)-round(0.150*fs)+ (x_i - 1);   % save index of bandpass 
                                  end
                                  qrs_amp_raw(Beat_C1) =  y_i;                                 % save amplitude of bandpass 
                                  SIG_LEV1 = 0.125*y_i + 0.875*SIG_LEV1;                       % adjust threshold for bandpass filtered sig
                              end
                             SIG_LEV = 0.125*pks(i) + 0.875*SIG_LEV ;                          % adjust Signal level
                            end
                                  
                        elseif (THR_NOISE <= pks(i)) && (pks(i) < THR_SIG)
                             NOISE_LEV1 = 0.125*y_i + 0.875*NOISE_LEV1;                        % adjust Noise level in filtered sig
                             NOISE_LEV = 0.125*pks(i) + 0.875*NOISE_LEV;                       % adjust Noise level in MVI       
                        elseif pks(i) < THR_NOISE
                            Noise_Count = Noise_Count + 1;
                            nois_c(Noise_Count) = pks(i);
                            nois_i(Noise_Count) = locs(i);    
                            NOISE_LEV1 = 0.125*y_i + 0.875*NOISE_LEV1;                         % noise level in filtered signal    
                            NOISE_LEV = 0.125*pks(i) + 0.875*NOISE_LEV;                        % adjust Noise level in MVI     
                        end
                                   
                        %% ================== adjust the threshold with SNR ============= %%
                        if NOISE_LEV ~= 0 || SIG_LEV ~= 0
                            THR_SIG = NOISE_LEV + 0.25*(abs(SIG_LEV - NOISE_LEV));
                            THR_NOISE = 0.5*(THR_SIG);
                        end
                        
                        %------ adjust the threshold with SNR for bandpassed signal -------- %
                        if NOISE_LEV1 ~= 0 || SIG_LEV1 ~= 0
                            THR_SIG1 = NOISE_LEV1 + 0.25*(abs(SIG_LEV1 - NOISE_LEV1));
                            THR_NOISE1 = 0.5*(THR_SIG1);
                        end
                        
                        
                    %--------- take a track of thresholds of smoothed signal -------------%
                    SIGL_buf(i) = SIG_LEV;
                    NOISL_buf(i) = NOISE_LEV;
                    THRS_buf(i) = THR_SIG;
                    %-------- take a track of thresholds of filtered signal ----------- %
                    SIGL_buf1(i) = SIG_LEV1;
                    NOISL_buf1(i) = NOISE_LEV1;
                    THRS_buf1(i) = THR_SIG1;
                    % ----------------------- reset parameters -------------------------- % 
                    skip = 0;                                                   
                    
                    ser_back = 0;    
                    end
                    %% ======================= Adjust Lengths ============================ %%
                    qrs_c = qrs_c(1:Beat_C);
                    qrs_i = qrs_i(1:Beat_C);
                    %% ======================= Plottings ================================= %%
                    
                    hold(app.UIAxes,'on')
                    
                    plot(app.UIAxes,ecg_m);
                       title(app.UIAxes,'QRS en la señal MVI');
                       hold on,plot(app.UIAxes,qrs_i,qrs_c,'o');
                       hold on,plot(app.UIAxes,locs,NOISL_buf,'LineWidth',2,'Linestyle','--','color','k');
                       hold on,plot(app.UIAxes,locs,SIGL_buf,'LineWidth',2,'Linestyle','-.','color','r');
                       hold on,plot(app.UIAxes,locs,THRS_buf,'LineWidth',2,'Linestyle','-.','color','g');
                       legend(app.UIAxes,'Carga','Crestas','Nivel de Ruido','Nivel de Señal','Limite adaptativo')
                      hold( app.UIAxes, 'off' )


                case 'Pulsaciones'
                    cla(app.UIAxes);


                    ecg = Ecg1(:); % vectorize
                    delay = 0;
                    skip = 0;                                                                  % becomes one when a T wave is detected
                    m_selected_RR = 0;
                    mean_RR = 0;
                    ser_back = 0; 
                    ax = zeros(1,6);
                    fs=1000;
                    gr = 1;  
                    
                    
                     ecg = ecg - mean(ecg);
                    
                    
                    f1=5;                                                                      % cuttoff low frequency to get rid of baseline wander
                     f2=15;                                                                     % cuttoff frequency to discard high frequency noise
                     Wn=[f1 f2]*2/fs;                                                           % cutt off based on fs
                     N = 3;                                                                     % order of 3 less processing
                     [a,b] = butter(N,Wn);   
                    
                    
                    ecg_h = filtfilt(a,b,ecg);
                     ecg_h = ecg_h/ max( abs(ecg_h));
                    
                     if fs ~= 200
                     int_c = (5-1)/(fs*1/40);
                     b = interp1(1:5,[1 2 0 -2 -1].*(1/8)*fs,1:int_c:5);
                    else
                     b = [1 2 0 -2 -1].*(1/8)*fs;   
                    end
                    
                    ecg_d = filtfilt(b,1,ecg_h);
                     ecg_d = ecg_d/max(ecg_d);
                     ecg_s = ecg_d.^2;
                     if fs ~= 200
                     int_c = (5-1)/(fs*1/40);
                     b = interp1(1:5,[1 2 0 -2 -1].*(1/8)*fs,1:int_c:5);
                    else
                     b = [1 2 0 -2 -1].*(1/8)*fs;   
                    end
                     ecg_d = filtfilt(b,1,ecg_h);
                     ecg_d = ecg_d/max(ecg_d);


                     ecg_m = conv(ecg_s ,ones(1 ,round(0.150*fs))/round(0.150*fs));
                    
                    
                      [pks,locs] = findpeaks(ecg_m,'MINPEAKDISTANCE',round(0.2*fs));
                    %% =================== Initialize Some Other Parameters =============== %%
                    LLp = length(pks);
                    % ---------------- Stores QRS wrt Sig and Filtered Sig ------------------%
                    qrs_c = zeros(1,LLp);           % amplitude of R
                    qrs_i = zeros(1,LLp);           % index
                    qrs_i_raw = zeros(1,LLp);       % amplitude of R
                    qrs_amp_raw= zeros(1,LLp);      % Index
                    % ------------------- Noise Buffers ---------------------------------%
                    nois_c = zeros(1,LLp);
                    nois_i = zeros(1,LLp);
                    % ------------------- Buffers for Signal and Noise ----------------- %
                    SIGL_buf = zeros(1,LLp);
                    NOISL_buf = zeros(1,LLp);
                    SIGL_buf1 = zeros(1,LLp);
                    NOISL_buf1 = zeros(1,LLp);
                    THRS_buf1 = zeros(1,LLp);
                    THRS_buf = zeros(1,LLp);
                    %% initialize the training phase (2 seconds of the signal) to determine the THR_SIG and THR_NOISE
                    THR_SIG = max(ecg_m(1:2*fs))*1/3;                                          % 0.25 of the max amplitude 
                    THR_NOISE = mean(ecg_m(1:2*fs))*1/2;                                       % 0.5 of the mean signal is considered to be noise
                    SIG_LEV= THR_SIG;
                    NOISE_LEV = THR_NOISE;
                    %% Initialize bandpath filter threshold(2 seconds of the bandpass signal)
                    THR_SIG1 = max(ecg_h(1:2*fs))*1/3;                                          % 0.25 of the max amplitude 
                    THR_NOISE1 = mean(ecg_h(1:2*fs))*1/2; 
                    SIG_LEV1 = THR_SIG1;                                                        % Signal level in Bandpassed filter
                    NOISE_LEV1 = THR_NOISE1;                                                    % Noise level in Bandpassed filter
                    %% ============ Thresholding and desicion rule ============= %%
                    Beat_C = 0;                                                                 % Raw Beats
                    Beat_C1 = 0;                                                                % Filtered Beats
                    Noise_Count = 0;                                                            % Noise Counter
                    for i = 1 : LLp  
                       %% ===== locate the corresponding peak in the filtered signal === %%
                        if locs(i)-round(0.150*fs)>= 1 && locs(i)<= length(ecg_h)
                              [y_i,x_i] = max(ecg_h(locs(i)-round(0.150*fs):locs(i)));
                           else
                              if i == 1
                                [y_i,x_i] = max(ecg_h(1:locs(i)));
                                ser_back = 1;
                              elseif locs(i)>= length(ecg_h)
                                [y_i,x_i] = max(ecg_h(locs(i)-round(0.150*fs):end));
                              end       
                        end       
                      %% ================= update the heart_rate ==================== %% 
                        if Beat_C >= 9        
                            diffRR = diff(qrs_i(Beat_C-8:Beat_C));                                   % calculate RR interval
                            mean_RR = mean(diffRR);                                            % calculate the mean of 8 previous R waves interval
                            comp =qrs_i(Beat_C)-qrs_i(Beat_C-1);                                     % latest RR
                        
                            if comp <= 0.92*mean_RR || comp >= 1.16*mean_RR
                         % ------ lower down thresholds to detect better in MVI -------- %
                                    THR_SIG = 0.5*(THR_SIG);
                                    THR_SIG1 = 0.5*(THR_SIG1);               
                            else
                                m_selected_RR = mean_RR;                                       % The latest regular beats mean
                            end 
                              
                        end
                        
                     %% == calculate the mean last 8 R waves to ensure that QRS is not ==== %%
                           if m_selected_RR
                               test_m = m_selected_RR;                                         %if the regular RR availabe use it   
                           elseif mean_RR && m_selected_RR == 0
                               test_m = mean_RR;   
                           else
                               test_m = 0;
                           end
                            
                        if test_m
                              if (locs(i) - qrs_i(Beat_C)) >= round(1.66*test_m)                  % it shows a QRS is missed 
                                  [pks_temp,locs_temp] = max(ecg_m(qrs_i(Beat_C)+ round(0.200*fs):locs(i)-round(0.200*fs))); % search back and locate the max in this interval
                                  locs_temp = qrs_i(Beat_C)+ round(0.200*fs) + locs_temp -1;      % location 
                                 
                                  if pks_temp > THR_NOISE
                                   Beat_C = Beat_C + 1;
                                   qrs_c(Beat_C) = pks_temp;
                                   qrs_i(Beat_C) = locs_temp;      
                                  % ------------- Locate in Filtered Sig ------------- %
                                   if locs_temp <= length(ecg_h)
                                      [y_i_t,x_i_t] = max(ecg_h(locs_temp-round(0.150*fs):locs_temp));
                                   else
                                      [y_i_t,x_i_t] = max(ecg_h(locs_temp-round(0.150*fs):end));
                                   end
                                  % ----------- Band pass Sig Threshold ------------------%
                                   if y_i_t > THR_NOISE1 
                                      Beat_C1 = Beat_C1 + 1;
                                      qrs_i_raw(Beat_C1) = locs_temp-round(0.150*fs)+ (x_i_t - 1);% save index of bandpass 
                                      qrs_amp_raw(Beat_C1) = y_i_t;                               % save amplitude of bandpass 
                                      SIG_LEV1 = 0.25*y_i_t + 0.75*SIG_LEV1;                      % when found with the second thres 
                                   end
                                   
                                   not_nois = 1;
                                   SIG_LEV = 0.25*pks_temp + 0.75*SIG_LEV ;                       % when found with the second threshold             
                                 end             
                              else
                                  not_nois = 0;         
                              end
                        end
                      
                        %% ===================  find noise and QRS peaks ================== %%
                        if pks(i) >= THR_SIG      
                          % ------ if No QRS in 360ms of the previous QRS See if T wave ------%
                           if Beat_C >= 3
                              if (locs(i)-qrs_i(Beat_C)) <= round(0.3600*fs)
                                  Slope1 = mean(diff(ecg_m(locs(i)-round(0.075*fs):locs(i))));       % mean slope of the waveform at that position
                                  Slope2 = mean(diff(ecg_m(qrs_i(Beat_C)-round(0.075*fs):qrs_i(Beat_C)))); % mean slope of previous R wave
                                  if abs(Slope1) <= abs(0.5*(Slope2))                              % slope less then 0.5 of previous R
                                     Noise_Count = Noise_Count + 1;
                                     nois_c(Noise_Count) = pks(i);
                                     nois_i(Noise_Count) = locs(i);
                                     skip = 1;                                                 % T wave identification
                                     % ----- adjust noise levels ------ %
                                     NOISE_LEV1 = 0.125*y_i + 0.875*NOISE_LEV1;
                                     NOISE_LEV = 0.125*pks(i) + 0.875*NOISE_LEV; 
                                  else
                                     skip = 0;
                                  end
                                
                               end
                            end
                            %---------- skip is 1 when a T wave is detected -------------- %
                            if skip == 0    
                              Beat_C = Beat_C + 1;
                              qrs_c(Beat_C) = pks(i);
                              qrs_i(Beat_C) = locs(i);
                            
                            %--------------- bandpass filter check threshold --------------- %
                              if y_i >= THR_SIG1  
                                  Beat_C1 = Beat_C1 + 1;
                                  if ser_back 
                                     qrs_i_raw(Beat_C1) = x_i;                                 % save index of bandpass 
                                  else
                                     qrs_i_raw(Beat_C1)= locs(i)-round(0.150*fs)+ (x_i - 1);   % save index of bandpass 
                                  end
                                  qrs_amp_raw(Beat_C1) =  y_i;                                 % save amplitude of bandpass 
                                  SIG_LEV1 = 0.125*y_i + 0.875*SIG_LEV1;                       % adjust threshold for bandpass filtered sig
                              end
                             SIG_LEV = 0.125*pks(i) + 0.875*SIG_LEV ;                          % adjust Signal level
                            end
                                  
                        elseif (THR_NOISE <= pks(i)) && (pks(i) < THR_SIG)
                             NOISE_LEV1 = 0.125*y_i + 0.875*NOISE_LEV1;                        % adjust Noise level in filtered sig
                             NOISE_LEV = 0.125*pks(i) + 0.875*NOISE_LEV;                       % adjust Noise level in MVI       
                        elseif pks(i) < THR_NOISE
                            Noise_Count = Noise_Count + 1;
                            nois_c(Noise_Count) = pks(i);
                            nois_i(Noise_Count) = locs(i);    
                            NOISE_LEV1 = 0.125*y_i + 0.875*NOISE_LEV1;                         % noise level in filtered signal    
                            NOISE_LEV = 0.125*pks(i) + 0.875*NOISE_LEV;                        % adjust Noise level in MVI     
                        end
                                   
                        %% ================== adjust the threshold with SNR ============= %%
                        if NOISE_LEV ~= 0 || SIG_LEV ~= 0
                            THR_SIG = NOISE_LEV + 0.25*(abs(SIG_LEV - NOISE_LEV));
                            THR_NOISE = 0.5*(THR_SIG);
                        end
                        
                        %------ adjust the threshold with SNR for bandpassed signal -------- %
                        if NOISE_LEV1 ~= 0 || SIG_LEV1 ~= 0
                            THR_SIG1 = NOISE_LEV1 + 0.25*(abs(SIG_LEV1 - NOISE_LEV1));
                            THR_NOISE1 = 0.5*(THR_SIG1);
                        end
                        
                        
                    %--------- take a track of thresholds of smoothed signal -------------%
                    SIGL_buf(i) = SIG_LEV;
                    NOISL_buf(i) = NOISE_LEV;
                    THRS_buf(i) = THR_SIG;
                    %-------- take a track of thresholds of filtered signal ----------- %
                    SIGL_buf1(i) = SIG_LEV1;
                    NOISL_buf1(i) = NOISE_LEV1;
                    THRS_buf1(i) = THR_SIG1;
                    % ----------------------- reset parameters -------------------------- % 
                    skip = 0;                                                   
                    not_nois = 0; 
                    ser_back = 0;    
                    end
                    %% ======================= Adjust Lengths ============================ %%
                    qrs_i_raw = qrs_i_raw(1:Beat_C1);
                    qrs_amp_raw = qrs_amp_raw(1:Beat_C1);
                    qrs_c = qrs_c(1:Beat_C);
                    qrs_i = qrs_i(1:Beat_C);
                    %% ======================= Plottings ================================= %%
                 hold(app.UIAxes,'on')
                    legend(app.UIAxes,'off')
                    plot(app.UIAxes,ecg-mean(ecg));
                       title(app.UIAxes,'Tren de pulsos del QRS encontrado en la señal de ECG');
                       %axis tight;
                       line(app.UIAxes,repmat(qrs_i_raw,[2 1]),...
                           repmat([min(ecg-mean(ecg))/2; max(ecg-mean(ecg))/2],size(qrs_i_raw)),...
                           'LineWidth',2.5,'LineStyle','-.','Color','r');
                       %linkaxes(az,'x');
                       %zoom on;
                    hold(app.UIAxes,'off')


                case 'QRS en señal filtrada'
                    cla(app.UIAxes);


                    ecg = Ecg1(:); % vectorize
                    delay = 0;
                    skip = 0;                                                                  % becomes one when a T wave is detected
                    m_selected_RR = 0;
                    mean_RR = 0;
                    ser_back = 0; 
                    ax = zeros(1,6);
                    fs=1000;
                    gr = 1;  
                    
                    
                     ecg = ecg - mean(ecg);
                    
                    
                    f1=5;                                                                      % cuttoff low frequency to get rid of baseline wander
                     f2=15;                                                                     % cuttoff frequency to discard high frequency noise
                     Wn=[f1 f2]*2/fs;                                                           % cutt off based on fs
                     N = 3;                                                                     % order of 3 less processing
                     [a,b] = butter(N,Wn);   
                    
                    
                    ecg_h = filtfilt(a,b,ecg);
                     ecg_h = ecg_h/ max( abs(ecg_h));
                    
                     if fs ~= 200
                     int_c = (5-1)/(fs*1/40);
                     b = interp1(1:5,[1 2 0 -2 -1].*(1/8)*fs,1:int_c:5);
                    else
                     b = [1 2 0 -2 -1].*(1/8)*fs;   
                    end
                    
                    ecg_d = filtfilt(b,1,ecg_h);
                     ecg_d = ecg_d/max(ecg_d);
                     ecg_s = ecg_d.^2;
                     if fs ~= 200
                     int_c = (5-1)/(fs*1/40);
                     b = interp1(1:5,[1 2 0 -2 -1].*(1/8)*fs,1:int_c:5);
                    else
                     b = [1 2 0 -2 -1].*(1/8)*fs;   
                    end
                     ecg_d = filtfilt(b,1,ecg_h);
                     ecg_d = ecg_d/max(ecg_d);


                     ecg_m = conv(ecg_s ,ones(1 ,round(0.150*fs))/round(0.150*fs));
                     
                    
                      [pks,locs] = findpeaks(ecg_m,'MINPEAKDISTANCE',round(0.2*fs));
                    %% =================== Initialize Some Other Parameters =============== %%
                    LLp = length(pks);
                    % ---------------- Stores QRS wrt Sig and Filtered Sig ------------------%
                    qrs_c = zeros(1,LLp);           % amplitude of R
                    qrs_i = zeros(1,LLp);           % index
                    qrs_i_raw = zeros(1,LLp);       % amplitude of R
                    qrs_amp_raw= zeros(1,LLp);      % Index
                    % ------------------- Noise Buffers ---------------------------------%
                    nois_c = zeros(1,LLp);
                    nois_i = zeros(1,LLp);
                    % ------------------- Buffers for Signal and Noise ----------------- %
                    SIGL_buf = zeros(1,LLp);
                    NOISL_buf = zeros(1,LLp);
                    SIGL_buf1 = zeros(1,LLp);
                    NOISL_buf1 = zeros(1,LLp);
                    THRS_buf1 = zeros(1,LLp);
                    THRS_buf = zeros(1,LLp);
                    %% initialize the training phase (2 seconds of the signal) to determine the THR_SIG and THR_NOISE
                    THR_SIG = max(ecg_m(1:2*fs))*1/3;                                          % 0.25 of the max amplitude 
                    THR_NOISE = mean(ecg_m(1:2*fs))*1/2;                                       % 0.5 of the mean signal is considered to be noise
                    SIG_LEV= THR_SIG;
                    NOISE_LEV = THR_NOISE;
                    %% Initialize bandpath filter threshold(2 seconds of the bandpass signal)
                    THR_SIG1 = max(ecg_h(1:2*fs))*1/3;                                          % 0.25 of the max amplitude 
                    THR_NOISE1 = mean(ecg_h(1:2*fs))*1/2; 
                    SIG_LEV1 = THR_SIG1;                                                        % Signal level in Bandpassed filter
                    NOISE_LEV1 = THR_NOISE1;                                                    % Noise level in Bandpassed filter
                    %% ============ Thresholding and desicion rule ============= %%
                    Beat_C = 0;                                                                 % Raw Beats
                    Beat_C1 = 0;                                                                % Filtered Beats
                    Noise_Count = 0;                                                            % Noise Counter
                    for i = 1 : LLp  
                       %% ===== locate the corresponding peak in the filtered signal === %%
                        if locs(i)-round(0.150*fs)>= 1 && locs(i)<= length(ecg_h)
                              [y_i,x_i] = max(ecg_h(locs(i)-round(0.150*fs):locs(i)));
                           else
                              if i == 1
                                [y_i,x_i] = max(ecg_h(1:locs(i)));
                                ser_back = 1;
                              elseif locs(i)>= length(ecg_h)
                                [y_i,x_i] = max(ecg_h(locs(i)-round(0.150*fs):end));
                              end       
                        end       
                      %% ================= update the heart_rate ==================== %% 
                        if Beat_C >= 9        
                            diffRR = diff(qrs_i(Beat_C-8:Beat_C));                                   % calculate RR interval
                            mean_RR = mean(diffRR);                                            % calculate the mean of 8 previous R waves interval
                            comp =qrs_i(Beat_C)-qrs_i(Beat_C-1);                                     % latest RR
                        
                            if comp <= 0.92*mean_RR || comp >= 1.16*mean_RR
                         % ------ lower down thresholds to detect better in MVI -------- %
                                    THR_SIG = 0.5*(THR_SIG);
                                    THR_SIG1 = 0.5*(THR_SIG1);               
                            else
                                m_selected_RR = mean_RR;                                       % The latest regular beats mean
                            end 
                              
                        end
                        
                     %% == calculate the mean last 8 R waves to ensure that QRS is not ==== %%
                           if m_selected_RR
                               test_m = m_selected_RR;                                         %if the regular RR availabe use it   
                           elseif mean_RR && m_selected_RR == 0
                               test_m = mean_RR;   
                           else
                               test_m = 0;
                           end
                            
                        if test_m
                              if (locs(i) - qrs_i(Beat_C)) >= round(1.66*test_m)                  % it shows a QRS is missed 
                                  [pks_temp,locs_temp] = max(ecg_m(qrs_i(Beat_C)+ round(0.200*fs):locs(i)-round(0.200*fs))); % search back and locate the max in this interval
                                  locs_temp = qrs_i(Beat_C)+ round(0.200*fs) + locs_temp -1;      % location 
                                 
                                  if pks_temp > THR_NOISE
                                   Beat_C = Beat_C + 1;
                                   qrs_c(Beat_C) = pks_temp;
                                   qrs_i(Beat_C) = locs_temp;      
                                  % ------------- Locate in Filtered Sig ------------- %
                                   if locs_temp <= length(ecg_h)
                                      [y_i_t,x_i_t] = max(ecg_h(locs_temp-round(0.150*fs):locs_temp));
                                   else
                                      [y_i_t,x_i_t] = max(ecg_h(locs_temp-round(0.150*fs):end));
                                   end
                                  % ----------- Band pass Sig Threshold ------------------%
                                   if y_i_t > THR_NOISE1 
                                      Beat_C1 = Beat_C1 + 1;
                                      qrs_i_raw(Beat_C1) = locs_temp-round(0.150*fs)+ (x_i_t - 1);% save index of bandpass 
                                      qrs_amp_raw(Beat_C1) = y_i_t;                               % save amplitude of bandpass 
                                      SIG_LEV1 = 0.25*y_i_t + 0.75*SIG_LEV1;                      % when found with the second thres 
                                   end
                                   
                                   not_nois = 1;
                                   SIG_LEV = 0.25*pks_temp + 0.75*SIG_LEV ;                       % when found with the second threshold             
                                 end             
                              else
                                  not_nois = 0;         
                              end
                        end
                      
                        %% ===================  find noise and QRS peaks ================== %%
                        if pks(i) >= THR_SIG      
                          % ------ if No QRS in 360ms of the previous QRS See if T wave ------%
                           if Beat_C >= 3
                              if (locs(i)-qrs_i(Beat_C)) <= round(0.3600*fs)
                                  Slope1 = mean(diff(ecg_m(locs(i)-round(0.075*fs):locs(i))));       % mean slope of the waveform at that position
                                  Slope2 = mean(diff(ecg_m(qrs_i(Beat_C)-round(0.075*fs):qrs_i(Beat_C)))); % mean slope of previous R wave
                                  if abs(Slope1) <= abs(0.5*(Slope2))                              % slope less then 0.5 of previous R
                                     Noise_Count = Noise_Count + 1;
                                     nois_c(Noise_Count) = pks(i);
                                     nois_i(Noise_Count) = locs(i);
                                     skip = 1;                                                 % T wave identification
                                     % ----- adjust noise levels ------ %
                                     NOISE_LEV1 = 0.125*y_i + 0.875*NOISE_LEV1;
                                     NOISE_LEV = 0.125*pks(i) + 0.875*NOISE_LEV; 
                                  else
                                     skip = 0;
                                  end
                                
                               end
                            end
                            %---------- skip is 1 when a T wave is detected -------------- %
                            if skip == 0    
                              Beat_C = Beat_C + 1;
                              qrs_c(Beat_C) = pks(i);
                              qrs_i(Beat_C) = locs(i);
                            
                            %--------------- bandpass filter check threshold --------------- %
                              if y_i >= THR_SIG1  
                                  Beat_C1 = Beat_C1 + 1;
                                  if ser_back 
                                     qrs_i_raw(Beat_C1) = x_i;                                 % save index of bandpass 
                                  else
                                     qrs_i_raw(Beat_C1)= locs(i)-round(0.150*fs)+ (x_i - 1);   % save index of bandpass 
                                  end
                                  qrs_amp_raw(Beat_C1) =  y_i;                                 % save amplitude of bandpass 
                                  SIG_LEV1 = 0.125*y_i + 0.875*SIG_LEV1;                       % adjust threshold for bandpass filtered sig
                              end
                             SIG_LEV = 0.125*pks(i) + 0.875*SIG_LEV ;                          % adjust Signal level
                            end
                                  
                        elseif (THR_NOISE <= pks(i)) && (pks(i) < THR_SIG)
                             NOISE_LEV1 = 0.125*y_i + 0.875*NOISE_LEV1;                        % adjust Noise level in filtered sig
                             NOISE_LEV = 0.125*pks(i) + 0.875*NOISE_LEV;                       % adjust Noise level in MVI       
                        elseif pks(i) < THR_NOISE
                            Noise_Count = Noise_Count + 1;
                            nois_c(Noise_Count) = pks(i);
                            nois_i(Noise_Count) = locs(i);    
                            NOISE_LEV1 = 0.125*y_i + 0.875*NOISE_LEV1;                         % noise level in filtered signal    
                            NOISE_LEV = 0.125*pks(i) + 0.875*NOISE_LEV;                        % adjust Noise level in MVI     
                        end
                                   
                        %% ================== adjust the threshold with SNR ============= %%
                        if NOISE_LEV ~= 0 || SIG_LEV ~= 0
                            THR_SIG = NOISE_LEV + 0.25*(abs(SIG_LEV - NOISE_LEV));
                            THR_NOISE = 0.5*(THR_SIG);
                        end
                        
                        %------ adjust the threshold with SNR for bandpassed signal -------- %
                        if NOISE_LEV1 ~= 0 || SIG_LEV1 ~= 0
                            THR_SIG1 = NOISE_LEV1 + 0.25*(abs(SIG_LEV1 - NOISE_LEV1));
                            THR_NOISE1 = 0.5*(THR_SIG1);
                        end
                        
                        
                    %--------- take a track of thresholds of smoothed signal -------------%
                    SIGL_buf(i) = SIG_LEV;
                    NOISL_buf(i) = NOISE_LEV;
                    THRS_buf(i) = THR_SIG;
                    %-------- take a track of thresholds of filtered signal ----------- %
                    SIGL_buf1(i) = SIG_LEV1;
                    NOISL_buf1(i) = NOISE_LEV1;
                    THRS_buf1(i) = THR_SIG1;
                    % ----------------------- reset parameters -------------------------- % 
                    skip = 0;                                                   
                    not_nois = 0; 
                    ser_back = 0;    
                    end
                    %% ======================= Adjust Lengths ============================ %%
                    qrs_i_raw = qrs_i_raw(1:Beat_C1);
                    qrs_amp_raw = qrs_amp_raw(1:Beat_C1);
                    qrs_c = qrs_c(1:Beat_C);
                    qrs_i = qrs_i(1:Beat_C);

                    hold(app.UIAxes,'on')
                    legend(app.UIAxes,'off')
                    
                     plot(app.UIAxes,ecg_h);
                       title(app.UIAxes,'QRS en señal filtrada');
                       %axis tight;
                       hold on,plot(app.UIAxes,qrs_i_raw,qrs_amp_raw,'o');
                       hold on,plot(app.UIAxes,locs,NOISL_buf1,'LineWidth',2,'Linestyle','--','color','k');
                       hold on,plot(app.UIAxes,locs,SIGL_buf1,'LineWidth',2,'Linestyle','-.','color','r');
                       hold on,plot(app.UIAxes,locs,THRS_buf1,'LineWidth',2,'Linestyle','-.','color','g');
                                           hold(app.UIAxes,'off')


                case 'Señal Digital'


                    cla(app.UIAxes)

                    ecg = Ecg1(:); % vectorize
                    delay = 0;
                    skip = 0;                                                                  % becomes one when a T wave is detected
                    m_selected_RR = 0;
                    mean_RR = 0;
                    ser_back = 0; 
                    ax = zeros(1,6);
                    fs=1000;
                    gr = 1;  
                    
                    
                     ecg = ecg - mean(ecg);
                    
                    
                    f1=5;                                                                      % cuttoff low frequency to get rid of baseline wander
                     f2=15;                                                                     % cuttoff frequency to discard high frequency noise
                     Wn=[f1 f2]*2/fs;                                                           % cutt off based on fs
                     N = 3;                                                                     % order of 3 less processing
                     [a,b] = butter(N,Wn);   
                    
                    
                    ecg_h = filtfilt(a,b,ecg);
                     ecg_h = ecg_h/ max( abs(ecg_h));
                    
                     if fs ~= 200
                     int_c = (5-1)/(fs*1/40);
                     b = interp1(1:5,[1 2 0 -2 -1].*(1/8)*fs,1:int_c:5);
                    else
                     b = [1 2 0 -2 -1].*(1/8)*fs;   
                    end
                    
                    ecg_d = filtfilt(b,1,ecg_h);
                     ecg_d = ecg_d/max(ecg_d);
                     ecg_s = ecg_d.^2;
                    
                     
                    plot(app.UIAxes,ecg_s);
                      %axis tight;
                      title(app.UIAxes,'Señal Digital');


            end
        end

        % Button pushed function: GuardarimagenButton
        function GuardarimagenButtonPushed(app, event)
             [FileName, PathName] = uiputfile('*.jpg', 'Guardar como');
             Name                 = fullfile(PathName,FileName);
             % get the handle to the child object axes2
             F= getframe(app.UIAxes);
             % assume one child only and grab the image data
             Image = frame2im(F);
            imwrite(Image, FileName)
        end

        % Button pushed function: GuardarimagenButton_2
        function GuardarimagenButton_2Pushed(app, event)
             [FileName, PathName] = uiputfile('*.jpg', 'Guardar como');
             Name                 = fullfile(PathName,FileName);
             % get the handle to the child object axes2
             F= getframe(app.UIAxes);
             % assume one child only and grab the image data
             Image = frame2im(F);
            imwrite(Image, FileName)
        end

        % Drop down opening function: FuncinDropDown
        function FuncinDropDownOpening(app, event)
            global Ecg1;


             tlabel = app.Label_2;

            ecg = (Ecg1)/200;
                fs = 360;                         %sampling frequency of 360 Hz
                Ts = 1/fs;
                t = (0:length(ecg) - 1)/fs;       %time variable


                %%%%%%%%%%%%%    Bandpass filter (5-40 Hz)     %%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                fl=5;                                                                      % cuttoff low frequency to get rid of baseline wander
                fh=40;                                                                     % cuttoff frequency to discard high frequency noise
                Wn=[fl fh]*2/fs;                                                           % cutt off based on fs                                                                    
                [a,b] = butter(3,Wn);                                                      % bandpass filtering
                ecg_h = filtfilt(a,b,ecg);
                ecg_h = ecg_h/ max( abs(ecg_h));


                %%%%%%%%%%%%%%%%%  Absolute CLT (ACLT) signal  %%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                N = length(ecg);
                w = 18;
                for i = 2+w:N
                    for k = i-w:i
                        dy = ecg_h(k)-ecg_h(k-1);
                        l(k+w-i+1) = abs(Ts.^2 + abs(4.*(dy^2)));
                    end
                    aclt(i) = sum(l);
                end
                
                aclt = circshift(aclt, [0 -fix(w/2)]);


                %%%%%%%%%%%%%%%%%   Peak Detection of ACLT Signal   %%%%%%%%%%%%%%%%%%%%%%%
                
                maxtab = [];
                mintab = [];
                
                aclt = aclt(:);
                
                mn = Inf; mx = -Inf;
                mnpos = NaN; mxpos = NaN;
                mnpos_s = NaN; mxpos_s = NaN;    
                
                lookformax = 1;
                delta = 0.5;
                x = (1:length(ecg));
                
                for i=1:length(aclt)
                  this = aclt(i);
                  if this > mx, mx = this; mxpos = t(i); mxpos_s = x(i); end
                  if this < mn, mn = this; mnpos = t(i); mnpos_s = x(i); end
                  
                  if lookformax
                    if this < mx-delta
                      maxtab = [maxtab ; mxpos_s mxpos];
                      mn = this; mnpos = t(i); mnpos_x = x(i);
                      lookformax = 0;
                    end  
                  else
                    if this > mn+delta
                      mintab = [mintab ; mnpos_s mnpos];
                      mx = this; mxpos = t(i); mxpos_s = x(i);
                      lookformax = 1;
                    end
                  end
                end
                
                qrs_time = [];
                qrs_sample = [];
                
                qrs_sample = maxtab(:,1);    %this vector contains the samples where there is a QRS Complex peak
                qrs_time = maxtab(:,2);      %this vector contains the times where there is a QRS Complex peak


                %%%%%%%%%%%%%   Peak detection of Raw ECG Signal (Fine tuning to find correct R peaks)   %%%%%%%%%%%%%%%%%%%%%%%%


                %%%%%%%%%%%%%% Calculation of R-R interval and Heart Rate    %%%%%%%%%%%%%%
                % 
                % 
                 beat_count = length(qrs_time);
                 N = length(ecg);
                 duration_in_seconds = N/fs; 
                duration_in_minutes = duration_in_seconds/60;
                 BPM = beat_count/duration_in_minutes;
                 
               
                 %tlabel.Text(BPM);


                 p = length(qrs_time);
                 avg_rr_interval = (qrs_time(p) - qrs_time(1))/beat_count;
                  tlabel.Text=strcat('Heart Rate (in BPM)'+"= " +BPM+ newline +'Average RR interval (in s)= '+ avg_rr_interval);
               %tlabel.Text=strcat('Average RR interval (in s)= '+ avg_rr_interval );
               % tlabel.Text(avg_rr_interval);
        end

        % Callback function
        function ButtonPushed(app, event)
          %  fig = app.UIFigure;
           % lbl = uiaxes(fig);
            
           % copyobj(app.UIAxes)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.SeleccionararchivoButton = uibutton(app.UIFigure, 'push');
            app.SeleccionararchivoButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButtonPushed, true);
            app.SeleccionararchivoButton.Text = 'Seleccionar archivo';
            app.GuardarimagenButton = uibutton(app.UIFigure, 'push');
            app.GuardarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarimagenButtonPushed, true);
            app.GuardarimagenButton.Text = 'Guardar imagen';
            app.GuardarimagenButton_2 = uibutton(app.UIFigure, 'push');
            app.GuardarimagenButton_2.ButtonPushedFcn = createCallbackFcn(app, @GuardarimagenButton_2Pushed, true);
            app.GuardarimagenButton_2.Text = 'Guardar imagen';
            app.FuncinDropDownLabel = uilabel(app.UIFigure);
            app.FuncinDropDownLabel.Text = 'Función';
            app.FuncinDropDown = uidropdown(app.UIFigure);
            app.FuncinDropDown.Items = {'Desplegar', 'Normalizacion', 'FFT', 'DEP', 'Señal sin tendencia', 'Picos y ondas sin filtro', 'Señal filtrada', 'Picos de umbral en la señal', 'QRS on MVI signal', 'Pulsaciones', 'QRS en señal filtrada', 'Señal Digital'};
            app.FuncinDropDown.DropDownOpeningFcn = createCallbackFcn(app, @FuncinDropDownOpening, true);
            app.FuncinDropDown.ValueChangedFcn = createCallbackFcn(app, @FuncinDropDownValueChanged, true);
            app.FuncinDropDown.Value = 'Desplegar';
            app.GUIparaprocesamientodesealesECGLabel = uilabel(app.UIFigure);
            app.GUIparaprocesamientodesealesECGLabel.Text = 'GUI para procesamiento de señales ECG';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end